classdef PttLotteryHistogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PttUIFigure        matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
        DownladButton      matlab.ui.control.Button
        StartMonthSpinner  matlab.ui.control.Spinner
        EndMonthSpinner    matlab.ui.control.Spinner
        Label              matlab.ui.control.Label
        StartYearSpinner   matlab.ui.control.Spinner
        EndYearSpinner     matlab.ui.control.Spinner
        Label_2            matlab.ui.control.Label
        LoadButton         matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
        Tab_2              matlab.ui.container.Tab
        UITable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        LotteryArray % Description
    end
    
    methods (Access = private)
        
        function HalfNum=FHNumConv(FullNum)
            if(FullNum=='１') HalfNum=1;end
            if(FullNum=='２') HalfNum=2;end
            if(FullNum=='３') HalfNum=3;end
            if(FullNum=='４') HalfNum=4;end
            if(FullNum=='５') HalfNum=5;end
            if(FullNum=='６') HalfNum=6;end
            if(FullNum=='７') HalfNum=7;end
            if(FullNum=='８') HalfNum=8;end
            if(FullNum=='９') HalfNum=9;end
        end
    
        function EnableSpinner(app)
            app.StartYearSpinner.Enable = 'on';
            app.StartMonthSpinner.Enable = 'on';
            app.EndYearSpinner.Enable = 'on';
            app.EndMonthSpinner.Enable = 'on';
        end
    end
    

    methods (Access = private)

        % Button pushed function: DownladButton
        function DownladButtonPushed(app, event)
            d = uiprogressdlg(app.PttUIFigure,'Title','請稍等','ShowPercentage','on');
            
            StartYear=2010;
            StartMon=8;
            EndYear=2019;
            EndMon=3;
            %% 分出年分
            YearURL='https://www.ptt.cc/man/PttEarnMoney/DEB7/D24C/index.html';
            YearStr = urlread(YearURL);
            YearExp = '<a[^>]*>(.*?)</a>';  %Year Expression
            YearMatches = regexp(YearStr,YearExp,'match');
            YearUrlArray = strings;
            for Mon=1:length(YearMatches)
                % Progress Bar Setting
                d.Value = Mon/length(YearMatches);
                d.Message = '分析網頁中的年份...';
                
                TorF=textscan(cell2mat(YearMatches(1,Mon)),'<a href="%s">');
                if(~cellfun(@isempty,TorF))
                    YearUrl=cell2mat(YearMatches(1,Mon));
                    if(YearUrlArray(1)=="")
                        YearUrlArray=string(YearUrl);
                    else
                        YearUrlArray(end+1)=string(YearUrl);
                    end
                end
            end
            %% 取得年分URL
            for Year=1:length(YearUrlArray)
                % Progress Bar Setting
                d.Value = Year/length(YearUrlArray);
                d.Message = '取得網頁中的年份...';
                
                Cref=textscan(YearUrlArray(Year),'<a href="%[^">]');
                Tref=cell2mat(Cref{1,1});
                YearUrlArray(Year)=['https://www.ptt.cc',Tref];
            end
            
            %%
            LotteryArray=[];
            for Year=StartYear-2006:EndYear-2006
                MonStr=urlread(char(YearUrlArray(Year)));
                MonExp = '<a[^>]*>(.*?)</a>';
                MonMatchess=regexp(MonStr,MonExp,'match');
                
                MonUrlArray=strings;
                for Mon=1:length(MonMatchess)
                    TorF=textscan(cell2mat(MonMatchess(1,Mon)),'<a href="%s">');
                    if(~cellfun(@isempty,TorF))
                        MonthUrl=cell2mat(MonMatchess(1,Mon));
                        if(MonUrlArray(1)=="")
                            MonUrlArray=string(MonthUrl);
                        else
                            MonUrlArray(end+1)=string(MonthUrl);
                        end
                    end
                end
                %% 分析每局
                if ((Year+2006)==StartYear)TempStartMon=StartMon;
                else TempStartMon=1;end
                if ((Year+2006)==EndYear)TempEndMon=EndMon;
                else TempEndMon=12;end
                %%
                for Month=TempStartMon:TempEndMon
                    % Progress Bar Setting
                    d.Value = ((Year-(StartYear-2006)-1)*12+Month+5)/(5+3+8*12);
                    d.Message = '建立資料庫...';
                    
                    Cref=textscan(MonUrlArray(Month),'<a href="%[^">]');
                    Tref=cell2mat(Cref{1,1});
                    LotteryURL=['https://www.ptt.cc',Tref];
                    
                    LotteryStr=urlread(LotteryURL);
                    MonExp = '<body[^>]*>(.*?)</body>';
                    LotteryBodyStr=regexp(LotteryStr,MonExp,'match');
                    LotteryCell=textscan(cell2mat(LotteryBodyStr),'%{MM/dd/yyyy}D %{HH:mm:ss}D %s');
                    for Lottery=1:length(LotteryCell{1,1})
                        if(~isnat(LotteryCell{1,1}(Lottery)) && ~isnat(LotteryCell{1,2}(Lottery)))
                            LotteraCNum=textscan(cell2mat(LotteryCell{1,3}(Lottery)),'[%c]');
                            if(isempty(cell2mat(LotteraCNum)))
                                continue
                            end
                            if(FHNumConv(cell2mat(LotteraCNum))==9) %口卡
                                continue
                            end
                            LotteryArray(end+1,:)=[Year+2006 Month FHNumConv(cell2mat(LotteraCNum))];
                        end
                    end
                end
            end
            % Close dialog box
            close(d);
            
            app.LotteryArray=LotteryArray;
            
            % Update Table
            app.UITable.Data=LotteryArray;
            
            % Enable Spinner
            EnableSpinner(app);
        end

        % Value changed function: EndMonthSpinner, EndYearSpinner, 
        % StartMonthSpinner, StartYearSpinner
        function SpinnerValueChanged(app, event)
            SearchStartYear=app.StartYearSpinner.Value;
            SearchStartMon=app.StartMonthSpinner.Value;
            SearchEndYear=app.EndYearSpinner.Value;
            SearchEndMon=app.EndMonthSpinner.Value;
            Index=[find(app.LotteryArray(:,1)==SearchStartYear & app.LotteryArray(:,2)==SearchStartMon,1):...
                find(app.LotteryArray(:,1)==SearchEndYear & app.LotteryArray(:,2)==SearchEndMon,1,'last')];
            histogram(app.UIAxes,app.LotteryArray(Index,3));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            savedData = app.LotteryArray;
            [FileName,PathName] = uiputfile('LotteryArray.mat','儲存資料');
            save([PathName,FileName],'savedData');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.mat','讀取資料','MultiSelect', 'off');
            if fileName==0
                return;
            end
            try
                load([pathName,fileName],'-mat');
                % Update Table
                app.UITable.Data=savedData;
                app.LotteryArray=savedData;
                % Enable Spinner
                EnableSpinner(app);
            catch
                errordlg(['There was a problem loading the .mat',' file'],'Load Error!');
                return;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PttUIFigure = uifigure;
            app.PttUIFigure.Name = 'Ptt彩券分析';
            app.TabGroup = uitabgroup(app.PttUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '分析';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '長條圖')
            xlabel(app.UIAxes, '開獎數字')
            ylabel(app.UIAxes, '次數')
            app.DownladButton = uibutton(app.Tab, 'push');
            app.DownladButton.ButtonPushedFcn = createCallbackFcn(app, @DownladButtonPushed, true);
            app.DownladButton.Text = '下載資料';
            app.StartMonthSpinner = uispinner(app.Tab);
            app.StartMonthSpinner.Limits = [1 12];
            app.StartMonthSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.StartMonthSpinner.Enable = 'off';
            app.StartMonthSpinner.Value = 10;
            app.EndMonthSpinner = uispinner(app.Tab);
            app.EndMonthSpinner.Limits = [1 12];
            app.EndMonthSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.EndMonthSpinner.Enable = 'off';
            app.EndMonthSpinner.Value = 3;
            app.Label = uilabel(app.Tab);
            app.Label.Text = '開始日期';
            app.StartYearSpinner = uispinner(app.Tab);
            app.StartYearSpinner.Limits = [2010 2019];
            app.StartYearSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.StartYearSpinner.Enable = 'off';
            app.StartYearSpinner.Value = 2010;
            app.EndYearSpinner = uispinner(app.Tab);
            app.EndYearSpinner.Limits = [2010 2019];
            app.EndYearSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.EndYearSpinner.Enable = 'off';
            app.EndYearSpinner.Value = 2019;
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '結束日期';
            app.SaveButton = uibutton(app.Tab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '儲存';
            app.LoadButton = uibutton(app.Tab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '讀取';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '資料';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'年'; '月'; '開獎數字'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = PttLotteryHistogram

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PttUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PttUIFigure)
        end
    end
end